classdef Final_Project_Feet_Classification < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        selectedpath                   matlab.ui.control.CheckBox
        FileEditField                  matlab.ui.control.EditField
        FileEditFieldLabel             matlab.ui.control.Label
        LoadButton                     matlab.ui.control.Button
        RGBDimensionsButtonGroup       matlab.ui.container.ButtonGroup
        AllButton                      matlab.ui.control.RadioButton
        BlueButton                     matlab.ui.control.RadioButton
        GreenButton                    matlab.ui.control.RadioButton
        RedButton                      matlab.ui.control.RadioButton
        UIAxes                         matlab.ui.control.UIAxes
        RightPanel                     matlab.ui.container.Panel
        TabGroup                       matlab.ui.container.TabGroup
        DataProcessingTab              matlab.ui.container.Tab
        ContactDetectionPanel          matlab.ui.container.Panel
        UIAxes2                        matlab.ui.control.UIAxes
        BackgroundDetectionPanel       matlab.ui.container.Panel
        IterationsEditField            matlab.ui.control.NumericEditField
        alliterationsLabel             matlab.ui.control.Label
        threshold2                     matlab.ui.control.Slider
        SameneighborthresholdSliderLabel  matlab.ui.control.Label
        testdetectionButton            matlab.ui.control.Button
        testButton                     matlab.ui.control.Button
        DetecWindow                    matlab.ui.control.NumericEditField
        DetectionWindowEditFieldLabel  matlab.ui.control.Label
        ThresholdSlider                matlab.ui.control.Slider
        ThresholdSliderLabel           matlab.ui.control.Label
        LandmarkIdentificationTab      matlab.ui.container.Tab
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = private)
        Property % Description
        directory
        file
    end

    methods (Access = private)

        function image_cut_out = background_removal(app, image, color, threshold)
            %color white = 1
            %color black = 0 

            SizeP = size(app.file.double);

            for i_row = 1 : SizeP(1) % rows
                for i_col = 1 : SizeP(2) % columns
                    if color
                        if image(i_row,i_col,1)>=(threshold/100) && image(i_row,i_col,2)>=(threshold/100) && image(i_row,i_col,3)>=(threshold/100)
                            image_cut_out(i_row,i_col) = 0; 
                        else
                            image_cut_out(i_row,i_col) = 1; 
                        end
                    else 
                         if image(i_row,i_col,1)<= 1-(threshold/100) && image(i_row,i_col,2)<= 1-(threshold/100) && image(i_row,i_col,3)<= 1-(threshold/100)
                            image_cut_out(i_row,i_col) = 0;
                                image_cut_out(i_row,i_col) = 0; 
                        else
                            image_cut_out(i_row,i_col) = 1; 
                        end

                    end
                end
            end


        end
        
        function output_logical = Neighborhood_refinement(app, image_logical)
         Size_log = size(image_logical);
         output_logical = nan(Size_log);
         Raster = ones(app.DetecWindow.Value); % Raster of detection
   
         Neighborhood = conv2(image_logical, Raster, 'same'); % determine the sum of the neighbouring pixels  
         
         for row = 1:size(image_logical,1)
             for col = 1:size(image_logical,2)
                 for dim = 1:size(image_logical,3)
                        cut_off = ( app.threshold2.Value .* numel(Raster) ) ./ 100;
                  
                     if Neighborhood(row,col,dim) >= cut_off
                         output_logical(row,col,dim) = 1;
                     elseif Neighborhood(row,col,dim) < cut_off
                         output_logical(row,col,dim) = 0;
                     end
                 end
             end
         end

        
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.file = struct();
            app.AllButton.Value = 1; 
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            if app.selectedpath.Value
                if ~exist(app.directory)
                    app.directory = uigetdir
                end
              
            else
                app.directory = uigetdir
            end
            cd(app.directory)
            app.file.raw = imread(uigetfile,'jpg');
            % transform into double
            app.file.double = cast(app.file.raw,'double') / 255; 
            % check wether backgroundcolor is white or black
            boarderpoint = app.file.double(1,1) + app.file.double(1,end ) + app.file.double(end,1) + app.file.double(end,end )
            if  boarderpoint >=2 
               app.file.backgroundcolor = 1; % white background 
            else 
               app.file.backgroundcolor = 0; % black background 
            end 
            
         
            % plot
            imshow(app.file.raw,"Parent",app.UIAxes)
            axis off
            % title

        end

        % Selection changed function: RGBDimensionsButtonGroup
        function RGBDimensionsButtonGroupSelectionChanged(app, event)
            selectedButton = app.RGBDimensionsButtonGroup.SelectedObject;
            if app.RedButton.Value
                temp_image(:,:,1) = app.file.raw(:,:,1);
                temp_image(:,:,2:3) = 0;
                imshow(temp_image,"Parent",app.UIAxes)
                axis off
            elseif app.BlueButton.Value 
                temp_image(:,:,3) = app.file.raw(:,:,3);
                temp_image(:,:,1:2) = 0;
%                 temp_image(:,:,3) = 0;
                imshow(temp_image,"Parent",app.UIAxes)
                axis off
            elseif app.GreenButton.Value
                temp_image(:,:,2) = app.file.raw(:,:,2);
                temp_image(:,:,1:2:3) = 0; 
                imshow(temp_image,"Parent",app.UIAxes)
                axis off
            elseif app.AllButton.Value
                imshow(app.file.raw,"Parent",app.UIAxes)
                axis off
            end 

        end

        % Button pushed function: testButton
        function testButtonPushed(app, event)
           app.file.cut_out_logical = app.background_removal(app.file.double, app.file.backgroundcolor, app.ThresholdSlider.Value)
           app.file.cut_out = app.file.cut_out_logical .* app.file.double;
           imshow(app.file.cut_out,"Parent",app.UIAxes)
        end

        % Button pushed function: testdetectionButton
        function testdetectionButtonPushed(app, event)
            set(gcf,'Pointer', 'watch'); 
            drawnow;
            image_logical = app.file.cut_out_logical;
       for i = 1:app.IterationsEditField.Value
           image_logical = Neighborhood_refinement(app, image_logical );

       end 
         app.file.cut_out_uint8 = cast(image_logical, 'uint8');
         app.file.image_DW = app.file.raw .* app.file.cut_out_uint8;
       
         imshow(app.file.image_DW ,"Parent",app.UIAxes);

         set(gcf,'Pointer', 'arrow');
         drawnow;
% --> subplot 
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {665, 665};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {582, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.LeftPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.RGBDimensionsButtonGroup = uibuttongroup(app.LeftPanel);
            app.RGBDimensionsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @RGBDimensionsButtonGroupSelectionChanged, true);
            app.RGBDimensionsButtonGroup.Title = 'RGB Dimensions: ';
            app.RedButton = uiradiobutton(app.RGBDimensionsButtonGroup);
            app.RedButton.Text = 'Red ';
            app.RedButton.Value = true;
            app.GreenButton = uiradiobutton(app.RGBDimensionsButtonGroup);
            app.GreenButton.Text = 'Green ';
            app.BlueButton = uiradiobutton(app.RGBDimensionsButtonGroup);
            app.BlueButton.Text = 'Blue';
            app.AllButton = uiradiobutton(app.RGBDimensionsButtonGroup);
            app.AllButton.Text = 'All';
            app.LoadButton = uibutton(app.LeftPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.FileEditFieldLabel = uilabel(app.LeftPanel);
            app.FileEditFieldLabel.Text = {'File: '; ''};
            app.FileEditField = uieditfield(app.LeftPanel, 'text');
            app.selectedpath = uicheckbox(app.LeftPanel);
            app.selectedpath.Text = 'selected path ';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.DataProcessingTab = uitab(app.TabGroup);
            app.DataProcessingTab.Title = 'Data Processing ';
            app.BackgroundDetectionPanel = uipanel(app.DataProcessingTab);
            app.BackgroundDetectionPanel.Title = 'Background Detection';
            app.ThresholdSliderLabel = uilabel(app.BackgroundDetectionPanel);
            app.ThresholdSliderLabel.Text = 'Threshold';
            app.ThresholdSlider = uislider(app.BackgroundDetectionPanel);
            app.DetectionWindowEditFieldLabel = uilabel(app.BackgroundDetectionPanel);
            app.DetectionWindowEditFieldLabel.Text = 'Detection Window';
            app.DetecWindow = uieditfield(app.BackgroundDetectionPanel, 'numeric');
            app.testButton = uibutton(app.BackgroundDetectionPanel, 'push');
            app.testButton.ButtonPushedFcn = createCallbackFcn(app, @testButtonPushed, true);
            app.testButton.Text = 'test';
            app.testdetectionButton = uibutton(app.BackgroundDetectionPanel, 'push');
            app.testdetectionButton.ButtonPushedFcn = createCallbackFcn(app, @testdetectionButtonPushed, true);
            app.testdetectionButton.Text = {'test detection'; ''};
            app.SameneighborthresholdSliderLabel = uilabel(app.BackgroundDetectionPanel);
            app.SameneighborthresholdSliderLabel.Text = {'Same neighbor threshold '; ''};
            app.threshold2 = uislider(app.BackgroundDetectionPanel);
            app.alliterationsLabel = uilabel(app.BackgroundDetectionPanel);
            app.alliterationsLabel.Text = {'Iterations '; ''};
            app.IterationsEditField = uieditfield(app.BackgroundDetectionPanel, 'numeric');
            app.ContactDetectionPanel = uipanel(app.DataProcessingTab);
            app.ContactDetectionPanel.Title = 'Contact Detection';
            app.UIAxes2 = uiaxes(app.ContactDetectionPanel);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.LandmarkIdentificationTab = uitab(app.TabGroup);
            app.LandmarkIdentificationTab.Title = 'Landmark Identification';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Final_Project_Feet_Classification

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end